classdef BayesianTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        BayesianIntegrationVisualizationToolLabel  matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        DCaseTab_2          matlab.ui.container.Tab
        sig2x_text_2        matlab.ui.control.NumericEditField
        x1yLabel            matlab.ui.control.Label
        yLabel              matlab.ui.control.Label
        xLabel              matlab.ui.control.Label
        Label_40            matlab.ui.control.Label
        xLabel_2            matlab.ui.control.Label
        cov2x2y_text_2      matlab.ui.control.NumericEditField
        yLabel_2            matlab.ui.control.Label
        x2yLabel            matlab.ui.control.Label
        Label_51            matlab.ui.control.Label
        mu2x_text_2         matlab.ui.control.NumericEditField
        Label_52            matlab.ui.control.Label
        mu2x_slider_2       matlab.ui.control.Slider
        Label_53            matlab.ui.control.Label
        mu2y_slider_2       matlab.ui.control.Slider
        Label_54            matlab.ui.control.Label
        sig2x_slider_2      matlab.ui.control.Slider
        EditField_3Label_5  matlab.ui.control.Label
        mu2y_text_2         matlab.ui.control.NumericEditField
        EditField_2Label_4  matlab.ui.control.Label
        cov1x1y_text_2      matlab.ui.control.NumericEditField
        Label_55            matlab.ui.control.Label
        cov1x1y_slider_2    matlab.ui.control.Slider
        Label_56            matlab.ui.control.Label
        sig2y_slider_2      matlab.ui.control.Slider
        Label_57            matlab.ui.control.Label
        cov2x2y_slider_2    matlab.ui.control.Slider
        EditField_3Label_6  matlab.ui.control.Label
        sig2y_text_2        matlab.ui.control.NumericEditField
        sig1y_text_2        matlab.ui.control.NumericEditField
        xLabel_3            matlab.ui.control.Label
        xLabel_4            matlab.ui.control.Label
        yLabel_3            matlab.ui.control.Label
        Label_61            matlab.ui.control.Label
        Label_62            matlab.ui.control.Label
        yLabel_4            matlab.ui.control.Label
        Label_64            matlab.ui.control.Label
        mu1y_text_2         matlab.ui.control.NumericEditField
        Label_65            matlab.ui.control.Label
        mu1y_slider_2       matlab.ui.control.Slider
        Label_66            matlab.ui.control.Label
        sig1x_slider_2      matlab.ui.control.Slider
        Label_67            matlab.ui.control.Label
        sig1y_slider_2      matlab.ui.control.Slider
        EditField_3Label_7  matlab.ui.control.Label
        sig1x_text_2        matlab.ui.control.NumericEditField
        EditField_2Label_5  matlab.ui.control.Label
        mu1x_text_2         matlab.ui.control.NumericEditField
        Label_68            matlab.ui.control.Label
        mu1x_slider_2       matlab.ui.control.Slider
        Label_69            matlab.ui.control.Label
        UIAxes_2            matlab.ui.control.UIAxes
        Label_70            matlab.ui.control.Label
        CLabel              matlab.ui.control.Label
        Label_71            matlab.ui.control.Label
        CLabel_2            matlab.ui.control.Label
        Label_72            matlab.ui.control.Label
        Label_73            matlab.ui.control.Label
        Label_74            matlab.ui.control.Label
        CLabel_3            matlab.ui.control.Label
        Label_75            matlab.ui.control.Label
        Label_76            matlab.ui.control.Label
        Label_77            matlab.ui.control.Label
        Label_78            matlab.ui.control.Label
        Label_79            matlab.ui.control.Label
        Label_80            matlab.ui.control.Label
        Label_81            matlab.ui.control.Label
        Label_82            matlab.ui.control.Label
        Label_83            matlab.ui.control.Label
        Label_84            matlab.ui.control.Label
        Label_86            matlab.ui.control.Label
        Label_87            matlab.ui.control.Label
        Label_88            matlab.ui.control.Label
        Label_89            matlab.ui.control.Label
        Label_91            matlab.ui.control.Label
        Label_92            matlab.ui.control.Label
        Label_93            matlab.ui.control.Label
        hLabel              matlab.ui.control.Label
        iLabel              matlab.ui.control.Label
        jLabel              matlab.ui.control.Label
        kLabel              matlab.ui.control.Label
        FLabel              matlab.ui.control.Label
        GLabel              matlab.ui.control.Label
        HLabel              matlab.ui.control.Label
        ILabel              matlab.ui.control.Label
        Label_94            matlab.ui.control.Label
        Label_95            matlab.ui.control.Label
        Label_96            matlab.ui.control.Label
        JLabel              matlab.ui.control.Label
        KLabel              matlab.ui.control.Label
        LLabel              matlab.ui.control.Label
        MLabel              matlab.ui.control.Label
        zLabel              matlab.ui.control.Label
        ALabel              matlab.ui.control.Label
        CLabel_4            matlab.ui.control.Label
        lLabel              matlab.ui.control.Label
        mLabel              matlab.ui.control.Label
        nLabel              matlab.ui.control.Label
        oLabel              matlab.ui.control.Label
        qLabel              matlab.ui.control.Label
        rLabel              matlab.ui.control.Label
        sLabel              matlab.ui.control.Label
        tLabel              matlab.ui.control.Label
        uLabel              matlab.ui.control.Label
        vLabel              matlab.ui.control.Label
        wLabel              matlab.ui.control.Label
        RLabel              matlab.ui.control.Label
        Label_97            matlab.ui.control.Label
        Label_98            matlab.ui.control.Label
        BLabel              matlab.ui.control.Label
        ULabel              matlab.ui.control.Label
        DLabel              matlab.ui.control.Label
        ELabel              matlab.ui.control.Label
        Label_99            matlab.ui.control.Label
        Label_100           matlab.ui.control.Label
        Label_101           matlab.ui.control.Label
        Label_102           matlab.ui.control.Label
        Label_103           matlab.ui.control.Label
        Label_104           matlab.ui.control.Label
        Label_145           matlab.ui.control.Label
        PLabel              matlab.ui.control.Label
        QLabel              matlab.ui.control.Label
        Label_146           matlab.ui.control.Label
        NLabel              matlab.ui.control.Label
        SLabel              matlab.ui.control.Label
        Label_90            matlab.ui.control.Label
        DCaseTab            matlab.ui.container.Tab
        Label_3             matlab.ui.control.Label
        mu1_slider_1        matlab.ui.control.Slider
        Label_2             matlab.ui.control.Label
        sig1_text_1         matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        sig1_slider_1       matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        mu2_slider_1        matlab.ui.control.Slider
        Label_6             matlab.ui.control.Label
        sig2_slider_1       matlab.ui.control.Slider
        EditField_2Label    matlab.ui.control.Label
        EditField_3Label    matlab.ui.control.Label
        mu2_text_1          matlab.ui.control.NumericEditField
        EditField_4Label    matlab.ui.control.Label
        sig2_text_1         matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
        Label_10            matlab.ui.control.Label
        Label_11            matlab.ui.control.Label
        Label_12            matlab.ui.control.Label
        UIAxes_1            matlab.ui.control.UIAxes
        TLabel              matlab.ui.control.Label
        Label_105           matlab.ui.control.Label
        eLabel              matlab.ui.control.Label
        Label_106           matlab.ui.control.Label
        aLabel              matlab.ui.control.Label
        bLabel              matlab.ui.control.Label
        cLabel              matlab.ui.control.Label
        dLabel              matlab.ui.control.Label
        Label_108           matlab.ui.control.Label
        Label_109           matlab.ui.control.Label
        Label_110           matlab.ui.control.Label
        fLabel              matlab.ui.control.Label
        gLabel              matlab.ui.control.Label
        Label_111           matlab.ui.control.Label
        Label_112           matlab.ui.control.Label
        Label_113           matlab.ui.control.Label
        Label_126           matlab.ui.control.Label
        CLabel_6            matlab.ui.control.Label
        Label_114           matlab.ui.control.Label
        Label_116           matlab.ui.control.Label
        Label_117           matlab.ui.control.Label
        Label_118           matlab.ui.control.Label
        Label_119           matlab.ui.control.Label
        CLabel_7            matlab.ui.control.Label
        Label_120           matlab.ui.control.Label
        Label_121           matlab.ui.control.Label
        Label_122           matlab.ui.control.Label
        Label_123           matlab.ui.control.Label
        Label_124           matlab.ui.control.Label
        Label_125           matlab.ui.control.Label
        Label_127           matlab.ui.control.Label
        Label_128           matlab.ui.control.Label
        Label_129           matlab.ui.control.Label
        Label_130           matlab.ui.control.Label
        Label_131           matlab.ui.control.Label
        Label_132           matlab.ui.control.Label
        Label_133           matlab.ui.control.Label
        Label_134           matlab.ui.control.Label
        Label_135           matlab.ui.control.Label
        Label_136           matlab.ui.control.Label
        Label_137           matlab.ui.control.Label
        Label_142           matlab.ui.control.Label
        XLabel              matlab.ui.control.Label
        YLabel              matlab.ui.control.Label
        ZLabel              matlab.ui.control.Label
        OLabel              matlab.ui.control.Label
        mu1_text_1          matlab.ui.control.NumericEditField
        BackgroundTab       matlab.ui.container.Tab
        UIAxes              matlab.ui.control.UIAxes
        DistributionButton  matlab.ui.control.Button
        ParametersButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        % Generate the data to plot.
        param1d = struct;
        param2d = struct;
    end
    
    methods (Access = private)
        
        function [g1,g2,gint] = script_1D(app,param)
            %% Plot two gaussians
            mu1 = param.mu1; mu2 = param.mu2; x = param.x;
            sig1 = param.sig1; sig2 = param.sig2;
            g1 = normpdf(x,mu1,sig1);
            g2 = normpdf(x,mu2,sig2);
            
            %% Integration
            w1 = (1/sig1)/((1/sig1)+(1/sig2));
            w2 = (1/sig2)/((1/sig1)+(1/sig2));
            
            muint = w1*mu1 + w2*mu2;
            sigint = (sig1*sig2)/(sig1 + sig2);
            gint = normpdf(x,muint,sigint);
            
            app.param1d.sigint = sigint;
            app.param1d.muint = muint;
            
        end
        
        
        function [g1,g2,gint] = script_2D(app,param)
            % Plot two gaussians
            mu1 = [param.mu1x param.mu1y]; mu2 = [param.mu2x param.mu2y];
            sig1 = [param.sig1xx,param.cov1;param.cov1,param.sig1yy];
            sig1 = (sig1' + sig1)./2;
            sig2 = [param.sig2xx,param.cov2;param.cov2,param.sig2yy];
            sig2 = (sig2' + sig2)./2;
            x1 = param.x1; x2 = param.x2;
            [X1,X2] = meshgrid(x1,x2);
            
            g1 = mvnpdf([X1(:) X2(:)],mu1,sig1);
            g1 = reshape(g1,length(x2),length(x1));
            g2 = mvnpdf([X1(:) X2(:)],mu2,sig2);
            g2 = reshape(g2,length(x2),length(x1));
            
            % Integration
            sigint = inv(inv(sig1) + inv(sig2));
            muint = (sigint*inv(sig1)*mu1' + sigint*inv(sig2)*mu2')';
            gint = mvnpdf([X1(:) X2(:)],muint,sigint);
            gint = reshape(gint,length(x2),length(x1));
            
            app.param2d.sigint = sigint;
            app.param2d.muint = muint;
            
        end
        
        function updateCalc(app)
             % Adjust calculation labels 
            app.aLabel.Text = int2str(app.param1d.sig1);
            app.bLabel.Text = int2str(app.param1d.sig1);
            app.cLabel.Text = int2str(app.param1d.sig2);
            app.dLabel.Text = int2str(app.param1d.mu1);
            app.XLabel.Text = int2str(app.param1d.sig2);
            app.YLabel.Text = int2str(app.param1d.sig2);
            app.ZLabel.Text = int2str(app.param1d.sig1);
            app.OLabel.Text = int2str(app.param1d.mu2);            
            app.eLabel.Text = int2str(app.param1d.muint);
            app.fLabel.Text = int2str(app.param1d.sig1);
            app.gLabel.Text = int2str(app.param1d.sig2);
            app.TLabel.Text = int2str(app.param1d.sigint);
            
           
            app.hLabel.Text = int2str(app.param2d.sigint(1,1)); 
            app.iLabel.Text = int2str(app.param2d.sigint(1,2));
            app.jLabel.Text = int2str(app.param2d.sigint(2,1));
            app.kLabel.Text = int2str(app.param2d.sigint(2,2));
            app.qLabel.Text = int2str(app.param2d.sigint(1,1)); 
            app.rLabel.Text = int2str(app.param2d.sigint(1,2));
            app.sLabel.Text = int2str(app.param2d.sigint(2,1));
            app.tLabel.Text = int2str(app.param2d.sigint(2,2));
            app.lLabel.Text = int2str(app.param2d.sig1xx); 
            app.mLabel.Text = int2str(app.param2d.cov1); 
            app.nLabel.Text = int2str(app.param2d.cov1);
            app.oLabel.Text = int2str(app.param2d.sig1yy);
            app.NLabel.Text = int2str(app.param2d.mu1x);
            app.SLabel.Text = int2str(app.param2d.mu1y);
            app.uLabel.Text = int2str(app.param2d.sig2xx);
            app.vLabel.Text = int2str(app.param2d.cov2);
            app.wLabel.Text = int2str(app.param2d.cov2);
            app.RLabel.Text = int2str(app.param2d.sig2yy);
            app.PLabel.Text = int2str(app.param2d.mu2x);
            app.QLabel.Text = int2str(app.param2d.mu2y);
            app.BLabel.Text = int2str(app.param2d.sig1xx);
            app.QLabel.Text = int2str(app.param2d.cov1);
            app.zLabel.Text = int2str(app.param2d.muint(1));
            app.ALabel.Text = int2str(app.param2d.muint(2));         
            app.ULabel.Text = int2str(app.param2d.cov1);
            app.DLabel.Text = int2str(app.param2d.cov1);
            app.ELabel.Text = int2str(app.param2d.sig1yy);
            app.FLabel.Text = int2str(app.param2d.sig2xx);
            app.GLabel.Text = int2str(app.param2d.cov2);
            app.HLabel.Text = int2str(app.param2d.cov2);
            app.ILabel.Text = int2str(app.param2d.sig2yy);
            app.JLabel.Text = int2str(app.param2d.sigint(1,1)); 
            app.KLabel.Text = int2str(app.param2d.sigint(1,2));
            app.LLabel.Text = int2str(app.param2d.sigint(2,1));
            app.MLabel.Text = int2str(app.param2d.sigint(2,2));
            
        end
        
        function backgroundTab(app)
           
        end
        
        function plotgauss2d(app)    

            [g1_2d,g2_2d,gint_2d] = script_2D(app,app.param2d);
            % Clear the axis of previous plots
            cla(app.UIAxes_2);
            % Plot the new data
            hold(app.UIAxes_2,'on');
            [~, h1] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,g1_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h1.LineColor = 'r';
            
            [~, h2] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,g2_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h2.LineColor = 'b';
            
            [~, h3] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,gint_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h3.LineColor = 'g';
        end
        
        function newValue = limitI(app,oldValue,typed)
            % Impose limits on covariance and variance to ensure no illegal entries
            % i.e. covariance cannot be more than the product of the square root of variances
            switch typed
                case 'cov1'
                    
                    minCov = -sqrt(app.param2d.sig1xx)*sqrt(app.param2d.sig1yy)+0.1;
                    maxCov = sqrt(app.param2d.sig1xx)*sqrt(app.param2d.sig1yy)-0.1;
                    if oldValue < minCov
                        newValue = minCov;
                    elseif oldValue > maxCov
                        newValue = maxCov;
                    else
                        newValue = oldValue;
                    end
                    
                case 'cov2'
                    
                    minCov = -sqrt(app.param2d.sig2xx)*sqrt(app.param2d.sig2yy)+0.1;
                    maxCov = sqrt(app.param2d.sig2xx)*sqrt(app.param2d.sig2yy)-0.1;  
                    if oldValue < minCov
                        newValue = minCov;
                    elseif oldValue > maxCov
                        newValue = maxCov;
                    else
                        newValue = oldValue;
                    end
                    
                case 'sig1x'
                    
                    minVar = abs(app.param2d.cov1)+0.1;
                    if sqrt(oldValue)*sqrt(app.param2d.sig1yy) < minVar
                        newValue = app.param2d.sig1xx;
                    else
                        newValue = oldValue;
                    end
                case 'sig1y'
                    
                    minVar = abs(app.param2d.cov1)+0.1;
                    if sqrt(oldValue)*sqrt(app.param2d.sig1xx) < minVar
                        newValue = app.param2d.sig1yy;
                    else
                        newValue = oldValue;
                    end
                case 'sig2x'
                    
                    minVar = abs(app.param2d.cov2)+0.1;
                    if sqrt(oldValue)*sqrt(app.param2d.sig2yy) < minVar
                        newValue = app.param2d.sig2xx;
                    else
                        newValue = oldValue;
                    end
                    
                case 'sig2y'
                    
                    minVar = abs(app.param2d.cov2)+0.1;
                    if sqrt(oldValue)*sqrt(app.param2d.sig2xx) < minVar
                        newValue = app.param2d.sig2yy;
                    else
                        newValue = oldValue;
                    end 
            end    
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.param1d.mu1 = -50;
            app.param1d.mu2 = 40;
            app.param1d.sig1 = 20;
            app.param1d.sig2 = 20;
            app.param1d.x = -200:1:200;
            [g1,g2,gint] = script_1D(app,app.param1d); % Call the function script_1D
            
            % Create a plot in the axes.
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
            
            %% 2D (generally the same as 1d with more parameters and diff way of plotting)
            % Generate the data to plot.
            app.param2d.mu1x = 10;
            app.param2d.mu1y = -10;
            app.param2d.mu2x = 20;
            app.param2d.mu2y = 30;
            app.param2d.sig1xx = 10;
            app.param2d.sig1yy = 10;
            app.param2d.cov1 = 5;
            app.param2d.sig2xx = 20;
            app.param2d.sig2yy = 20;
            app.param2d.cov2 = 5;
            app.param2d.x1 = [-30:1:70];
            app.param2d.x2 = [-30:1:70];
            app.sig1x_slider_2.Limits = [1 40];
            app.sig2x_slider_2.Limits = [1 40];
            app.sig1y_slider_2.Limits = [1 40];
            app.sig2y_slider_2.Limits = [1 40];
            app.cov2x2y_slider_2.Limits = [-40 40];
            app.cov1x1y_slider_2.Limits = [-40 40];
            app.sig1x_text_2.Limits = [1 40];
            app.sig2x_text_2.Limits = [1 40];
            app.sig1y_text_2.Limits = [1 40];
            app.sig2y_text_2.Limits = [1 40];
            app.cov2x2y_text_2.Limits = [-40 40];
            app.cov1x1y_text_2.Limits = [-40 40];
            [g1_2d,g2_2d,gint_2d] = script_2D(app,app.param2d); % Call function script_2D
            
            updateCalc(app);
            
            % Create a plot in the axes
            hold(app.UIAxes_2,'on');
            [~, h1] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,g1_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h1.LineColor = 'r';
            
            [~, h2] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,g2_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h2.LineColor = 'b';
            
            [~, h3] = contour(app.UIAxes_2,app.param2d.x1,app.param2d.x2,gint_2d,[.0001 .001 .01 .05:.1:.95 .99 .999 .9999]);
            h3.LineColor = 'g';
            
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab; 
        end

        % Value changing function: cov1x1y_slider_2
        function cov1x1y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'cov1');
            app.param2d.cov1 = changingValue;
            app.cov1x1y_slider_2.Value = changingValue;
            app.cov1x1y_text_2.Value = changingValue;
               
            plotgauss2d(app);
            updateCalc(app);
        end

        % Value changed function: cov1x1y_text_2
        function cov1x1y_text_2ValueChanged(app, event)
            value = app.cov1x1y_text_2.Value;
            value = limitI(app,value,'cov1');
            app.param2d.cov1 = value;
            app.cov1x1y_slider_2.Value = value;
            app.cov1x1y_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
        end

        % Value changing function: cov2x2y_slider_2
        function cov2x2y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'cov2');
            app.param2d.cov2 = changingValue;
            app.cov2x2y_slider_2.Value = changingValue;
            app.cov2x2y_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
        end

        % Value changed function: cov2x2y_text_2
        function cov2x2y_text_2ValueChanged(app, event)
            value = app.cov2x2y_text_2.Value;
            value = limitI(app,value,'cov2');
            app.param2d.cov2 = value;
            app.cov2x2y_slider_2.Value = value;
            app.cov2x2y_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
        end

        % Value changing function: mu1_slider_1
        function mu1_slider_1ValueChanging(app, event)
            changingValue = event.Value;
            app.param1d.mu1 = changingValue;
            app.mu1_text_1.Value = changingValue;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changed function: mu1_text_1
        function mu1_text_1ValueChanged(app, event)
            value = app.mu1_text_1.Value;
            app.param1d.mu1 = value;
            app.mu1_slider_1.Value = value;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changing function: mu1x_slider_2
        function mu1x_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.param2d.mu1x = changingValue;
            app.mu1x_slider_2.Value = changingValue;
            app.mu1x_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);  
        end

        % Value changed function: mu1x_text_2
        function mu1x_text_2ValueChanged(app, event)
            value = app.mu1x_text_2.Value;
            app.param2d.mu1x = value;
            app.mu1x_slider_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
        
        end

        % Value changing function: mu1y_slider_2
        function mu1y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.param2d.mu1y = changingValue;
            app.mu1y_slider_2.Value = changingValue;
            app.mu1y_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
               
        end

        % Value changed function: mu1y_text_2
        function mu1y_text_2ValueChanged(app, event)
            value = app.mu1y_text_2.Value;
            app.param2d.mu1y = value;
            app.mu1y_slider_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
        end

        % Value changed function: mu2_slider_1
        function mu2_slider_1ValueChanged(app, event)
            value = app.mu2_slider_1.Value;
            app.param1d.mu2 = value;
            app.mu2_slider_1.Value = value;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changing function: mu2_slider_1
        function mu2_slider_1ValueChanging(app, event)
            changingValue = event.Value;
            app.param1d.mu2 = changingValue;
            app.mu2_text_1.Value = changingValue;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changed function: mu2_text_1
        function mu2_text_1ValueChanged(app, event)
            value = app.mu2_text_1.Value;
            app.param1d.mu2 = value;
            app.mu2_slider_1.Value = value;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changing function: mu2x_slider_2
        function mu2x_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.param2d.mu2x = changingValue;
            app.mu2x_slider_2.Value = changingValue;
            app.mu2x_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: mu2x_text_2
        function mu2x_text_2ValueChanged(app, event)
            value = app.mu2x_text_2.Value;
            app.param2d.mu2x = value;
            app.mu2x_slider_2.Value = value;
            
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changing function: mu2y_slider_2
        function mu2y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.param2d.mu2y = changingValue;
            app.mu2y_slider_2.Value = changingValue;
            app.mu2y_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: mu2y_text_2
        function mu2y_text_2ValueChanged(app, event)
            value = app.mu2y_text_2.Value;
            app.param2d.mu2y = value;
            app.mu2y_slider_2.Value = value;
                     
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changing function: sig1_slider_1
        function sig1_slider_1ValueChanging(app, event)
            changingValue = event.Value;
            app.param1d.sig1 = changingValue;
            app.sig1_text_1.Value = changingValue;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changed function: sig1_text_1
        function sig1_text_1ValueChanged(app, event)
            value = app.sig1_text_1.Value;
            app.param1d.sig1 = value;
            app.sig1_slider_1.Value = value;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changing function: sig1x_slider_2
        function sig1x_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'sig1x');
            app.param2d.sig1xx = changingValue;
            app.sig1x_slider_2.Value = changingValue;
            app.sig1x_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: sig1x_text_2
        function sig1x_text_2ValueChanged(app, event)
            value = app.sig1x_text_2.Value;
            value = limitI(app,value,'sig1x');
            app.param2d.sig1xx = value;
            app.sig1x_slider_2.Value = value;
            app.sig1x_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
            
            
        end

        % Value changing function: sig1y_slider_2
        function sig1y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'sig1y');
            app.param2d.sig1yy = changingValue;
            app.sig1y_slider_2.Value = changingValue;
            app.sig1y_text_2.Value = changingValue;      
             
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: sig1y_text_2
        function sig1y_text_2ValueChanged(app, event)
            value = app.sig1y_text_2.Value;
            value = limitI(app,value,'sig1y');
            app.param2d.sig1yy = value;
            app.sig1y_slider_2.Value = value;
            app.sig1y_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changing function: sig2_slider_1
        function sig2_slider_1ValueChanging(app, event)
            changingValue = event.Value;
            app.param1d.sig2 = changingValue;
            app.sig2_text_1.Value = changingValue;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changed function: sig2_text_1
        function sig2_text_1ValueChanged(app, event)
            value = app.sig2_text_1.Value;
            app.param1d.sig2 = value;
            app.sig2_slider_1.Value = value;
            [g1,g2,gint] = script_1D(app,app.param1d);
            updateCalc(app);
            plot(app.UIAxes_1,app.param1d.x,g1,'r',app.param1d.x,g2,'b',app.param1d.x,gint,'g');
        end

        % Value changing function: sig2x_slider_2
        function sig2x_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'sig2x');
            app.param2d.sig2xx = changingValue;
            app.sig2x_slider_2.Value = changingValue;
            app.sig2x_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: sig2x_text_2
        function sig2x_text_2ValueChanged(app, event)
            value = app.sig2x_text_2.Value;
            value = limitI(app,value,'sig2x');
            app.param2d.sig2xx = value;
            app.sig2x_slider_2.Value = value;
            app.sig2x_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
          
        end

        % Value changing function: sig2y_slider_2
        function sig2y_slider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = limitI(app,changingValue,'sig2y');
            app.param2d.sig2yy = changingValue;
            app.sig2y_slider_2.Value = changingValue;
            app.sig2y_text_2.Value = changingValue;
            
            plotgauss2d(app);
            updateCalc(app);
            
        end

        % Value changed function: sig2y_text_2
        function sig2y_text_2ValueChanged(app, event)
            value = app.sig2y_text_2.Value;
            value = limitI(app,value,'sig2y');
            app.param2d.sig2yy = value;
            app.sig2y_slider_2.Value = value;
            app.sig2y_text_2.Value = value;
            
            plotgauss2d(app);
            updateCalc(app);
        end

        % Button pushed function: DistributionButton
        function DistributionButtonPushed(app, event)
            imshow('Proof1.jpg', 'Parent', app.UIAxes)
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            imshow('Proof11.jpg', 'Parent', app.UIAxes)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '                                                                                  ';
            app.BayesianIntegrationVisualizationToolLabel = uilabel(app.UIFigure);
            app.BayesianIntegrationVisualizationToolLabel.Text = 'Bayesian Integration Visualization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DCaseTab_2 = uitab(app.TabGroup);
            app.DCaseTab_2.Title = '2D Case';
            app.sig2x_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.sig2x_text_2.ValueChangedFcn = createCallbackFcn(app, @sig2x_text_2ValueChanged, true);
            app.sig2x_text_2.Limits = [1 40];
            app.sig2x_text_2.ValueDisplayFormat = '%.0f';
            app.sig2x_text_2.Value = 20;
            app.x1yLabel = uilabel(app.DCaseTab_2);
            app.x1yLabel.Text = '1x,1y';
            app.yLabel = uilabel(app.DCaseTab_2);
            app.yLabel.Text = '2y';
            app.xLabel = uilabel(app.DCaseTab_2);
            app.xLabel.Text = '2x';
            app.Label_40 = uilabel(app.DCaseTab_2);
            app.Label_40.Text = '2';
            app.xLabel_2 = uilabel(app.DCaseTab_2);
            app.xLabel_2.Text = '2x';
            app.cov2x2y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.cov2x2y_text_2.ValueChangedFcn = createCallbackFcn(app, @cov2x2y_text_2ValueChanged, true);
            app.cov2x2y_text_2.Limits = [-40 40];
            app.cov2x2y_text_2.ValueDisplayFormat = '%.0f';
            app.cov2x2y_text_2.Value = 5;
            app.yLabel_2 = uilabel(app.DCaseTab_2);
            app.yLabel_2.Text = '2y';
            app.x2yLabel = uilabel(app.DCaseTab_2);
            app.x2yLabel.Text = '2x,2y';
            app.Label_51 = uilabel(app.DCaseTab_2);
            app.Label_51.Text = '';
            app.mu2x_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.mu2x_text_2.ValueChangedFcn = createCallbackFcn(app, @mu2x_text_2ValueChanged, true);
            app.mu2x_text_2.Limits = [-30 70];
            app.mu2x_text_2.ValueDisplayFormat = '%.0f';
            app.mu2x_text_2.Value = 20;
            app.Label_52 = uilabel(app.DCaseTab_2);
            app.Label_52.Text = 'μ';
            app.mu2x_slider_2 = uislider(app.DCaseTab_2);
            app.mu2x_slider_2.Limits = [-30 70];
            app.mu2x_slider_2.MajorTicks = [];
            app.mu2x_slider_2.MajorTickLabels = {};
            app.mu2x_slider_2.ValueChangingFcn = createCallbackFcn(app, @mu2x_slider_2ValueChanging, true);
            app.mu2x_slider_2.MinorTicks = [];
            app.mu2x_slider_2.Value = 20;
            app.Label_53 = uilabel(app.DCaseTab_2);
            app.Label_53.Text = 'μ';
            app.mu2y_slider_2 = uislider(app.DCaseTab_2);
            app.mu2y_slider_2.Limits = [-30 70];
            app.mu2y_slider_2.MajorTicks = [];
            app.mu2y_slider_2.MajorTickLabels = {};
            app.mu2y_slider_2.ValueChangingFcn = createCallbackFcn(app, @mu2y_slider_2ValueChanging, true);
            app.mu2y_slider_2.MinorTicks = [];
            app.mu2y_slider_2.Value = 30;
            app.Label_54 = uilabel(app.DCaseTab_2);
            app.Label_54.Text = 'σ';
            app.sig2x_slider_2 = uislider(app.DCaseTab_2);
            app.sig2x_slider_2.Limits = [1 40];
            app.sig2x_slider_2.MajorTicks = [];
            app.sig2x_slider_2.MajorTickLabels = {};
            app.sig2x_slider_2.ValueChangingFcn = createCallbackFcn(app, @sig2x_slider_2ValueChanging, true);
            app.sig2x_slider_2.MinorTicks = [];
            app.sig2x_slider_2.Value = 20;
            app.EditField_3Label_5 = uilabel(app.DCaseTab_2);
            app.EditField_3Label_5.Text = '';
            app.mu2y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.mu2y_text_2.ValueChangedFcn = createCallbackFcn(app, @mu2y_text_2ValueChanged, true);
            app.mu2y_text_2.Limits = [-30 70];
            app.mu2y_text_2.ValueDisplayFormat = '%.0f';
            app.mu2y_text_2.Value = 30;
            app.EditField_2Label_4 = uilabel(app.DCaseTab_2);
            app.EditField_2Label_4.Text = '';
            app.cov1x1y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.cov1x1y_text_2.ValueChangedFcn = createCallbackFcn(app, @cov1x1y_text_2ValueChanged, true);
            app.cov1x1y_text_2.Limits = [-40 40];
            app.cov1x1y_text_2.ValueDisplayFormat = '%.0f';
            app.cov1x1y_text_2.Value = 5;
            app.Label_55 = uilabel(app.DCaseTab_2);
            app.Label_55.Text = 'Σ';
            app.cov1x1y_slider_2 = uislider(app.DCaseTab_2);
            app.cov1x1y_slider_2.Limits = [-40 40];
            app.cov1x1y_slider_2.MajorTicks = [];
            app.cov1x1y_slider_2.MajorTickLabels = {};
            app.cov1x1y_slider_2.ValueChangingFcn = createCallbackFcn(app, @cov1x1y_slider_2ValueChanging, true);
            app.cov1x1y_slider_2.MinorTicks = [];
            app.cov1x1y_slider_2.Value = 5;
            app.Label_56 = uilabel(app.DCaseTab_2);
            app.Label_56.Text = 'σ';
            app.sig2y_slider_2 = uislider(app.DCaseTab_2);
            app.sig2y_slider_2.Limits = [1 40];
            app.sig2y_slider_2.MajorTicks = [];
            app.sig2y_slider_2.MajorTickLabels = {};
            app.sig2y_slider_2.ValueChangingFcn = createCallbackFcn(app, @sig2y_slider_2ValueChanging, true);
            app.sig2y_slider_2.MinorTicks = [];
            app.sig2y_slider_2.Value = 20;
            app.Label_57 = uilabel(app.DCaseTab_2);
            app.Label_57.Text = 'Σ';
            app.cov2x2y_slider_2 = uislider(app.DCaseTab_2);
            app.cov2x2y_slider_2.Limits = [-40 40];
            app.cov2x2y_slider_2.MajorTicks = [];
            app.cov2x2y_slider_2.MajorTickLabels = {};
            app.cov2x2y_slider_2.ValueChangingFcn = createCallbackFcn(app, @cov2x2y_slider_2ValueChanging, true);
            app.cov2x2y_slider_2.MinorTicks = [];
            app.cov2x2y_slider_2.Value = 5;
            app.EditField_3Label_6 = uilabel(app.DCaseTab_2);
            app.EditField_3Label_6.Text = '';
            app.sig2y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.sig2y_text_2.ValueChangedFcn = createCallbackFcn(app, @sig2y_text_2ValueChanged, true);
            app.sig2y_text_2.Limits = [1 40];
            app.sig2y_text_2.ValueDisplayFormat = '%.0f';
            app.sig2y_text_2.Value = 20;
            app.sig1y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.sig1y_text_2.ValueChangedFcn = createCallbackFcn(app, @sig1y_text_2ValueChanged, true);
            app.sig1y_text_2.Limits = [1 40];
            app.sig1y_text_2.ValueDisplayFormat = '%.0f';
            app.sig1y_text_2.Value = 10;
            app.xLabel_3 = uilabel(app.DCaseTab_2);
            app.xLabel_3.Text = '1x';
            app.xLabel_4 = uilabel(app.DCaseTab_2);
            app.xLabel_4.Text = '1x';
            app.yLabel_3 = uilabel(app.DCaseTab_2);
            app.yLabel_3.Text = '1y';
            app.Label_61 = uilabel(app.DCaseTab_2);
            app.Label_61.Text = '2';
            app.Label_62 = uilabel(app.DCaseTab_2);
            app.Label_62.Text = '2';
            app.yLabel_4 = uilabel(app.DCaseTab_2);
            app.yLabel_4.Text = '1y';
            app.Label_64 = uilabel(app.DCaseTab_2);
            app.Label_64.Text = '';
            app.mu1y_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.mu1y_text_2.ValueChangedFcn = createCallbackFcn(app, @mu1y_text_2ValueChanged, true);
            app.mu1y_text_2.Limits = [-30 70];
            app.mu1y_text_2.ValueDisplayFormat = '%.0f';
            app.mu1y_text_2.Value = -10;
            app.Label_65 = uilabel(app.DCaseTab_2);
            app.Label_65.Text = 'μ';
            app.mu1y_slider_2 = uislider(app.DCaseTab_2);
            app.mu1y_slider_2.Limits = [-30 70];
            app.mu1y_slider_2.MajorTicks = [];
            app.mu1y_slider_2.MajorTickLabels = {};
            app.mu1y_slider_2.ValueChangingFcn = createCallbackFcn(app, @mu1y_slider_2ValueChanging, true);
            app.mu1y_slider_2.MinorTicks = [];
            app.mu1y_slider_2.Value = -10;
            app.Label_66 = uilabel(app.DCaseTab_2);
            app.Label_66.Text = 'σ';
            app.sig1x_slider_2 = uislider(app.DCaseTab_2);
            app.sig1x_slider_2.Limits = [1 40];
            app.sig1x_slider_2.MajorTicks = [];
            app.sig1x_slider_2.MajorTickLabels = {};
            app.sig1x_slider_2.ValueChangingFcn = createCallbackFcn(app, @sig1x_slider_2ValueChanging, true);
            app.sig1x_slider_2.MinorTicks = [];
            app.sig1x_slider_2.Value = 10;
            app.Label_67 = uilabel(app.DCaseTab_2);
            app.Label_67.Text = 'σ';
            app.sig1y_slider_2 = uislider(app.DCaseTab_2);
            app.sig1y_slider_2.Limits = [1 40];
            app.sig1y_slider_2.MajorTicks = [];
            app.sig1y_slider_2.MajorTickLabels = {};
            app.sig1y_slider_2.ValueChangingFcn = createCallbackFcn(app, @sig1y_slider_2ValueChanging, true);
            app.sig1y_slider_2.MinorTicks = [];
            app.sig1y_slider_2.Value = 10;
            app.EditField_3Label_7 = uilabel(app.DCaseTab_2);
            app.EditField_3Label_7.Text = '';
            app.sig1x_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.sig1x_text_2.ValueChangedFcn = createCallbackFcn(app, @sig1x_text_2ValueChanged, true);
            app.sig1x_text_2.Limits = [1 40];
            app.sig1x_text_2.ValueDisplayFormat = '%.0f';
            app.sig1x_text_2.Value = 10;
            app.EditField_2Label_5 = uilabel(app.DCaseTab_2);
            app.EditField_2Label_5.Text = '';
            app.mu1x_text_2 = uieditfield(app.DCaseTab_2, 'numeric');
            app.mu1x_text_2.ValueChangedFcn = createCallbackFcn(app, @mu1x_text_2ValueChanged, true);
            app.mu1x_text_2.Limits = [-30 70];
            app.mu1x_text_2.ValueDisplayFormat = '%.0f';
            app.mu1x_text_2.Value = 10;
            app.Label_68 = uilabel(app.DCaseTab_2);
            app.Label_68.Text = 'μ';
            app.mu1x_slider_2 = uislider(app.DCaseTab_2);
            app.mu1x_slider_2.Limits = [-30 70];
            app.mu1x_slider_2.MajorTicks = [];
            app.mu1x_slider_2.MajorTickLabels = {};
            app.mu1x_slider_2.ValueChangingFcn = createCallbackFcn(app, @mu1x_slider_2ValueChanging, true);
            app.mu1x_slider_2.MinorTicks = [];
            app.mu1x_slider_2.Value = 10;
            app.Label_69 = uilabel(app.DCaseTab_2);
            app.Label_69.Text = '2';
            app.UIAxes_2 = uiaxes(app.DCaseTab_2);
            xlabel(app.UIAxes_2, 'X position')
            ylabel(app.UIAxes_2, 'Y position')
            app.Label_70 = uilabel(app.DCaseTab_2);
            app.Label_70.Text = 'Σ  Σ  μ  + Σ  Σ  μ ';
            app.CLabel = uilabel(app.DCaseTab_2);
            app.CLabel.Text = 'C';
            app.Label_71 = uilabel(app.DCaseTab_2);
            app.Label_71.Text = 'μ  :';
            app.CLabel_2 = uilabel(app.DCaseTab_2);
            app.CLabel_2.Text = 'C';
            app.Label_72 = uilabel(app.DCaseTab_2);
            app.Label_72.Text = '1';
            app.Label_73 = uilabel(app.DCaseTab_2);
            app.Label_73.Text = '-1';
            app.Label_74 = uilabel(app.DCaseTab_2);
            app.Label_74.Text = '1';
            app.CLabel_3 = uilabel(app.DCaseTab_2);
            app.CLabel_3.Text = 'C';
            app.Label_75 = uilabel(app.DCaseTab_2);
            app.Label_75.Text = '2';
            app.Label_76 = uilabel(app.DCaseTab_2);
            app.Label_76.Text = '-1';
            app.Label_77 = uilabel(app.DCaseTab_2);
            app.Label_77.Text = '2';
            app.Label_78 = uilabel(app.DCaseTab_2);
            app.Label_78.Text = '2';
            app.Label_79 = uilabel(app.DCaseTab_2);
            app.Label_79.Text = '1';
            app.Label_80 = uilabel(app.DCaseTab_2);
            app.Label_80.Text = '= ';
            app.Label_81 = uilabel(app.DCaseTab_2);
            app.Label_81.Text = '= ';
            app.Label_82 = uilabel(app.DCaseTab_2);
            app.Label_82.Text = '[    ]';
            app.Label_83 = uilabel(app.DCaseTab_2);
            app.Label_83.Text = '[    ]';
            app.Label_84 = uilabel(app.DCaseTab_2);
            app.Label_84.Text = '        + ';
            app.Label_86 = uilabel(app.DCaseTab_2);
            app.Label_86.Text = '[    ]';
            app.Label_87 = uilabel(app.DCaseTab_2);
            app.Label_87.Text = '[    ]';
            app.Label_88 = uilabel(app.DCaseTab_2);
            app.Label_88.Text = '= ';
            app.Label_89 = uilabel(app.DCaseTab_2);
            app.Label_89.Text = '= ';
            app.Label_91 = uilabel(app.DCaseTab_2);
            app.Label_91.Text = '-1';
            app.Label_92 = uilabel(app.DCaseTab_2);
            app.Label_92.Text = '[    ]';
            app.Label_93 = uilabel(app.DCaseTab_2);
            app.Label_93.Text = '[    ]';
            app.hLabel = uilabel(app.DCaseTab_2);
            app.hLabel.Text = 'h';
            app.iLabel = uilabel(app.DCaseTab_2);
            app.iLabel.Text = 'i';
            app.jLabel = uilabel(app.DCaseTab_2);
            app.jLabel.Text = 'j';
            app.kLabel = uilabel(app.DCaseTab_2);
            app.kLabel.Text = 'k';
            app.FLabel = uilabel(app.DCaseTab_2);
            app.FLabel.Text = 'F';
            app.GLabel = uilabel(app.DCaseTab_2);
            app.GLabel.Text = 'G';
            app.HLabel = uilabel(app.DCaseTab_2);
            app.HLabel.Text = 'H';
            app.ILabel = uilabel(app.DCaseTab_2);
            app.ILabel.Text = 'I';
            app.Label_94 = uilabel(app.DCaseTab_2);
            app.Label_94.Text = '-1';
            app.Label_95 = uilabel(app.DCaseTab_2);
            app.Label_95.Text = '-1';
            app.Label_96 = uilabel(app.DCaseTab_2);
            app.Label_96.Text = '[  ]';
            app.JLabel = uilabel(app.DCaseTab_2);
            app.JLabel.Text = 'J';
            app.KLabel = uilabel(app.DCaseTab_2);
            app.KLabel.Text = 'K';
            app.LLabel = uilabel(app.DCaseTab_2);
            app.LLabel.Text = 'L';
            app.MLabel = uilabel(app.DCaseTab_2);
            app.MLabel.Text = 'M';
            app.zLabel = uilabel(app.DCaseTab_2);
            app.zLabel.Text = 'z';
            app.ALabel = uilabel(app.DCaseTab_2);
            app.ALabel.Text = 'A';
            app.CLabel_4 = uilabel(app.DCaseTab_2);
            app.CLabel_4.Text = 'C';
            app.lLabel = uilabel(app.DCaseTab_2);
            app.lLabel.Text = 'l';
            app.mLabel = uilabel(app.DCaseTab_2);
            app.mLabel.Text = 'm';
            app.nLabel = uilabel(app.DCaseTab_2);
            app.nLabel.Text = 'n';
            app.oLabel = uilabel(app.DCaseTab_2);
            app.oLabel.Text = 'o';
            app.qLabel = uilabel(app.DCaseTab_2);
            app.qLabel.Text = 'q';
            app.rLabel = uilabel(app.DCaseTab_2);
            app.rLabel.Text = 'r';
            app.sLabel = uilabel(app.DCaseTab_2);
            app.sLabel.Text = 's';
            app.tLabel = uilabel(app.DCaseTab_2);
            app.tLabel.Text = 't';
            app.uLabel = uilabel(app.DCaseTab_2);
            app.uLabel.Text = 'u';
            app.vLabel = uilabel(app.DCaseTab_2);
            app.vLabel.Text = 'v';
            app.wLabel = uilabel(app.DCaseTab_2);
            app.wLabel.Text = 'w';
            app.RLabel = uilabel(app.DCaseTab_2);
            app.RLabel.Text = 'R';
            app.Label_97 = uilabel(app.DCaseTab_2);
            app.Label_97.Text = '-1';
            app.Label_98 = uilabel(app.DCaseTab_2);
            app.Label_98.Text = '-1';
            app.BLabel = uilabel(app.DCaseTab_2);
            app.BLabel.Text = 'B';
            app.ULabel = uilabel(app.DCaseTab_2);
            app.ULabel.Text = 'U';
            app.DLabel = uilabel(app.DCaseTab_2);
            app.DLabel.Text = 'D';
            app.ELabel = uilabel(app.DCaseTab_2);
            app.ELabel.Text = 'E';
            app.Label_99 = uilabel(app.DCaseTab_2);
            app.Label_99.Text = '(Σ  + Σ  ) ';
            app.Label_100 = uilabel(app.DCaseTab_2);
            app.Label_100.Text = 'Σ  :';
            app.Label_101 = uilabel(app.DCaseTab_2);
            app.Label_101.Text = '-1';
            app.Label_102 = uilabel(app.DCaseTab_2);
            app.Label_102.Text = '-1';
            app.Label_103 = uilabel(app.DCaseTab_2);
            app.Label_103.Text = '-1';
            app.Label_104 = uilabel(app.DCaseTab_2);
            app.Label_104.Text = '[    ]';
            app.Label_145 = uilabel(app.DCaseTab_2);
            app.Label_145.Text = '[  ]';
            app.PLabel = uilabel(app.DCaseTab_2);
            app.PLabel.Text = 'P';
            app.QLabel = uilabel(app.DCaseTab_2);
            app.QLabel.Text = 'Q';
            app.Label_146 = uilabel(app.DCaseTab_2);
            app.Label_146.Text = '[  ]';
            app.NLabel = uilabel(app.DCaseTab_2);
            app.NLabel.Text = 'N';
            app.SLabel = uilabel(app.DCaseTab_2);
            app.SLabel.Text = 'S';
            app.Label_90 = uilabel(app.DCaseTab_2);
            app.Label_90.Text = '(             )';
            app.DCaseTab = uitab(app.TabGroup);
            app.DCaseTab.Title = '1D Case';
            app.Label_3 = uilabel(app.DCaseTab);
            app.Label_3.Text = 'μ';
            app.mu1_slider_1 = uislider(app.DCaseTab);
            app.mu1_slider_1.Limits = [-200 200];
            app.mu1_slider_1.MajorTicks = [];
            app.mu1_slider_1.MajorTickLabels = {};
            app.mu1_slider_1.ValueChangingFcn = createCallbackFcn(app, @mu1_slider_1ValueChanging, true);
            app.mu1_slider_1.MinorTicks = [];
            app.mu1_slider_1.Value = -50;
            app.Label_2 = uilabel(app.DCaseTab);
            app.Label_2.Text = '';
            app.sig1_text_1 = uieditfield(app.DCaseTab, 'numeric');
            app.sig1_text_1.ValueChangedFcn = createCallbackFcn(app, @sig1_text_1ValueChanged, true);
            app.sig1_text_1.Limits = [-200 200];
            app.sig1_text_1.ValueDisplayFormat = '%.0f';
            app.sig1_text_1.Value = 40;
            app.Label_4 = uilabel(app.DCaseTab);
            app.Label_4.Text = 'σ';
            app.sig1_slider_1 = uislider(app.DCaseTab);
            app.sig1_slider_1.Limits = [0 200];
            app.sig1_slider_1.MajorTicks = [];
            app.sig1_slider_1.MajorTickLabels = {};
            app.sig1_slider_1.ValueChangingFcn = createCallbackFcn(app, @sig1_slider_1ValueChanging, true);
            app.sig1_slider_1.MinorTicks = [];
            app.sig1_slider_1.Value = 40;
            app.Label_5 = uilabel(app.DCaseTab);
            app.Label_5.Text = 'μ';
            app.mu2_slider_1 = uislider(app.DCaseTab);
            app.mu2_slider_1.Limits = [-200 200];
            app.mu2_slider_1.MajorTicks = [];
            app.mu2_slider_1.MajorTickLabels = {};
            app.mu2_slider_1.ValueChangedFcn = createCallbackFcn(app, @mu2_slider_1ValueChanged, true);
            app.mu2_slider_1.ValueChangingFcn = createCallbackFcn(app, @mu2_slider_1ValueChanging, true);
            app.mu2_slider_1.MinorTicks = [];
            app.mu2_slider_1.Value = 20;
            app.Label_6 = uilabel(app.DCaseTab);
            app.Label_6.Text = 'σ';
            app.sig2_slider_1 = uislider(app.DCaseTab);
            app.sig2_slider_1.Limits = [0 200];
            app.sig2_slider_1.MajorTicks = [];
            app.sig2_slider_1.MajorTickLabels = {};
            app.sig2_slider_1.ValueChangingFcn = createCallbackFcn(app, @sig2_slider_1ValueChanging, true);
            app.sig2_slider_1.MinorTicks = [];
            app.sig2_slider_1.Value = 20;
            app.EditField_2Label = uilabel(app.DCaseTab);
            app.EditField_2Label.Text = '';
            app.EditField_3Label = uilabel(app.DCaseTab);
            app.EditField_3Label.Text = '';
            app.mu2_text_1 = uieditfield(app.DCaseTab, 'numeric');
            app.mu2_text_1.ValueChangedFcn = createCallbackFcn(app, @mu2_text_1ValueChanged, true);
            app.mu2_text_1.Limits = [-200 200];
            app.mu2_text_1.ValueDisplayFormat = '%.0f';
            app.mu2_text_1.Value = 20;
            app.EditField_4Label = uilabel(app.DCaseTab);
            app.EditField_4Label.Text = '';
            app.sig2_text_1 = uieditfield(app.DCaseTab, 'numeric');
            app.sig2_text_1.ValueChangedFcn = createCallbackFcn(app, @sig2_text_1ValueChanged, true);
            app.sig2_text_1.Limits = [-200 200];
            app.sig2_text_1.ValueDisplayFormat = '%.0f';
            app.sig2_text_1.Value = 20;
            app.Label_7 = uilabel(app.DCaseTab);
            app.Label_7.Text = '1';
            app.Label_8 = uilabel(app.DCaseTab);
            app.Label_8.Text = '2';
            app.Label_9 = uilabel(app.DCaseTab);
            app.Label_9.Text = '1';
            app.Label_10 = uilabel(app.DCaseTab);
            app.Label_10.Text = '2';
            app.Label_11 = uilabel(app.DCaseTab);
            app.Label_11.Text = '2';
            app.Label_12 = uilabel(app.DCaseTab);
            app.Label_12.Text = '2';
            app.UIAxes_1 = uiaxes(app.DCaseTab);
            xlabel(app.UIAxes_1, 'μ')
            ylabel(app.UIAxes_1, 'Probability')
            app.TLabel = uilabel(app.DCaseTab);
            app.TLabel.Text = 'T';
            app.Label_105 = uilabel(app.DCaseTab);
            app.Label_105.Text = '= ';
            app.eLabel = uilabel(app.DCaseTab);
            app.eLabel.Text = 'e';
            app.Label_106 = uilabel(app.DCaseTab);
            app.Label_106.Text = '= ';
            app.aLabel = uilabel(app.DCaseTab);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.DCaseTab);
            app.bLabel.Text = 'b';
            app.cLabel = uilabel(app.DCaseTab);
            app.cLabel.Text = 'c';
            app.dLabel = uilabel(app.DCaseTab);
            app.dLabel.Text = 'd';
            app.Label_108 = uilabel(app.DCaseTab);
            app.Label_108.Text = '= ';
            app.Label_109 = uilabel(app.DCaseTab);
            app.Label_109.Text = '= ';
            app.Label_110 = uilabel(app.DCaseTab);
            app.Label_110.Text = '((1/      ) + (1/      ))';
            app.fLabel = uilabel(app.DCaseTab);
            app.fLabel.Text = 'f';
            app.gLabel = uilabel(app.DCaseTab);
            app.gLabel.Text = 'g';
            app.Label_111 = uilabel(app.DCaseTab);
            app.Label_111.Text = '-1';
            app.Label_112 = uilabel(app.DCaseTab);
            app.Label_112.Text = '-1';
            app.Label_113 = uilabel(app.DCaseTab);
            app.Label_113.Text = 'μ  :';
            app.Label_126 = uilabel(app.DCaseTab);
            app.Label_126.Text = {'(1/σ   )/((1/σ   )+(1/σ   ))μ + (1/σ   )/((1/σ   )+(1/σ   ))μ '; ''};
            app.CLabel_6 = uilabel(app.DCaseTab);
            app.CLabel_6.Text = 'C';
            app.Label_114 = uilabel(app.DCaseTab);
            app.Label_114.Text = '1';
            app.Label_116 = uilabel(app.DCaseTab);
            app.Label_116.Text = '2';
            app.Label_117 = uilabel(app.DCaseTab);
            app.Label_117.Text = '2';
            app.Label_118 = uilabel(app.DCaseTab);
            app.Label_118.Text = 'σ  :';
            app.Label_119 = uilabel(app.DCaseTab);
            app.Label_119.Text = '((1/σ  ) + (1/σ  ))';
            app.CLabel_7 = uilabel(app.DCaseTab);
            app.CLabel_7.Text = 'C';
            app.Label_120 = uilabel(app.DCaseTab);
            app.Label_120.Text = '2';
            app.Label_121 = uilabel(app.DCaseTab);
            app.Label_121.Text = '1';
            app.Label_122 = uilabel(app.DCaseTab);
            app.Label_122.Text = '2';
            app.Label_123 = uilabel(app.DCaseTab);
            app.Label_123.Text = '2';
            app.Label_124 = uilabel(app.DCaseTab);
            app.Label_124.Text = '2';
            app.Label_125 = uilabel(app.DCaseTab);
            app.Label_125.Text = '';
            app.Label_127 = uilabel(app.DCaseTab);
            app.Label_127.Text = '2';
            app.Label_128 = uilabel(app.DCaseTab);
            app.Label_128.Text = '2';
            app.Label_129 = uilabel(app.DCaseTab);
            app.Label_129.Text = '2';
            app.Label_130 = uilabel(app.DCaseTab);
            app.Label_130.Text = '2';
            app.Label_131 = uilabel(app.DCaseTab);
            app.Label_131.Text = '2';
            app.Label_132 = uilabel(app.DCaseTab);
            app.Label_132.Text = '2';
            app.Label_133 = uilabel(app.DCaseTab);
            app.Label_133.Text = '2';
            app.Label_134 = uilabel(app.DCaseTab);
            app.Label_134.Text = '2';
            app.Label_135 = uilabel(app.DCaseTab);
            app.Label_135.Text = '1';
            app.Label_136 = uilabel(app.DCaseTab);
            app.Label_136.Text = '1';
            app.Label_137 = uilabel(app.DCaseTab);
            app.Label_137.Text = '1';
            app.Label_142 = uilabel(app.DCaseTab);
            app.Label_142.Text = {'(1/      )/((1/      )+(1/      ))       + (1/      )/((1/      )+(1/      ))  '; ''};
            app.XLabel = uilabel(app.DCaseTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.DCaseTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.DCaseTab);
            app.ZLabel.Text = 'Z';
            app.OLabel = uilabel(app.DCaseTab);
            app.OLabel.Text = 'O';
            app.mu1_text_1 = uieditfield(app.DCaseTab, 'numeric');
            app.mu1_text_1.ValueChangedFcn = createCallbackFcn(app, @mu1_text_1ValueChanged, true);
            app.mu1_text_1.Limits = [-200 200];
            app.mu1_text_1.Value = -50;
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.UIAxes = uiaxes(app.BackgroundTab);
            app.DistributionButton = uibutton(app.BackgroundTab, 'push');
            app.DistributionButton.ButtonPushedFcn = createCallbackFcn(app, @DistributionButtonPushed, true);
            app.DistributionButton.Text = 'Distribution';
            app.ParametersButton = uibutton(app.BackgroundTab, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BayesianTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end